classdef UAVTrackingRadarSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ComponentsMenu                matlab.ui.container.Menu
        ReceivedSignalPanel           matlab.ui.container.Panel
        OriginalSignalPanel           matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        ReceivedSignalInformationTab  matlab.ui.container.Tab
        OriginalWaveFreq              matlab.ui.control.UIAxes
        VCOAmplifierTab               matlab.ui.container.Tab
        UpConverterTab                matlab.ui.container.Tab
        Amplifier2Tab                 matlab.ui.container.Tab
        TargetTab                     matlab.ui.container.Tab
        VCOTab                        matlab.ui.container.Tab
        DownConverterTab              matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        Amplifier1Tab                 matlab.ui.container.Tab
        FirstAmplifierGainKnob        matlab.ui.control.Knob
        FirstAmplifierGainKnobLabel   matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        OriginalWaveTab               matlab.ui.container.Tab
        MaxTimeEditField              matlab.ui.control.NumericEditField
        MaxTimeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        WaveTypeDropDown              matlab.ui.control.DropDown
        WaveTypeDropDownLabel         matlab.ui.control.Label
        GenerateWaveButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        waveform % Description
        timeVector % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateWaveButton
        function GenerateWaveButtonPushed(app, event)
            Fs = app.SamplingFrequencyEditField.Value;
            app.timeVector = 0:1/Fs:app.MaxTimeEditField.Value;
            t = app.timeVector;
            f = app.FrequencyEditField.Value;
            a = app.AmplitudeEditField.Value;
            
            if app.WaveTypeDropDown.Value ==  "Sine"
                app.waveform = a*sin(2*pi*f.*t);
            elseif app.WaveTypeDropDown.Value ==  "Square"
                app.waveform = a*square(2*pi*f.*t); % Use square wave generator for square waveform
            elseif app.WaveTypeDropDown.Value == "Chirp"
                app.waveform = a*chirp(t, 0, Fs, f, 'linear');
            end
            s1 = app.waveform;
            
            plot(app.UIAxes, t, s1)
            plot(app.UIAxes2, t, s1)

            originalFFT = fft(s1);
            frequencies = linspace(0, Fs, length(originalFFT));
            plot(app.OriginalWaveFreq,frequencies,abs(originalFFT))
        end

        % Value changing function: FirstAmplifierGainKnob
        function FirstAmplifierGainKnobValueChanging(app, event)
            changingValue = event.Value;
            s1 = app.waveform * changingValue;
            t = app.timeVector;
            plot(app.UIAxes2,t,s1)
            plot(app.UIAxes3,t,s1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OriginalWaveTab = uitab(app.TabGroup);
            app.OriginalWaveTab.Title = 'Original Wave';
            app.UIAxes = uiaxes(app.OriginalWaveTab);
            title(app.UIAxes, 'Original Wave')
            xlabel(app.UIAxes, 'Time (sec.)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GenerateWaveButton = uibutton(app.OriginalWaveTab, 'push');
            app.GenerateWaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveButtonPushed, true);
            app.GenerateWaveButton.Text = 'Generate Wave';
            app.WaveTypeDropDownLabel = uilabel(app.OriginalWaveTab);
            app.WaveTypeDropDownLabel.Text = 'Wave Type';
            app.WaveTypeDropDown = uidropdown(app.OriginalWaveTab);
            app.WaveTypeDropDown.Items = {'Sine', 'Square', 'Chirp'};
            app.WaveTypeDropDown.Value = 'Sine';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.OriginalWaveTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.OriginalWaveTab, 'numeric');
            app.SamplingFrequencyEditField.Value = 25000000;
            app.FrequencyEditFieldLabel = uilabel(app.OriginalWaveTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.OriginalWaveTab, 'numeric');
            app.FrequencyEditField.Value = 1000000000;
            app.AmplitudeEditFieldLabel = uilabel(app.OriginalWaveTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.OriginalWaveTab, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.MaxTimeEditFieldLabel = uilabel(app.OriginalWaveTab);
            app.MaxTimeEditFieldLabel.Text = 'Max Time';
            app.MaxTimeEditField = uieditfield(app.OriginalWaveTab, 'numeric');
            app.MaxTimeEditField.Value = 1;
            app.Amplifier1Tab = uitab(app.TabGroup);
            app.Amplifier1Tab.Title = 'Amplifier 1';
            app.UIAxes2 = uiaxes(app.Amplifier1Tab);
            title(app.UIAxes2, 'Amplified Wave')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FirstAmplifierGainKnobLabel = uilabel(app.Amplifier1Tab);
            app.FirstAmplifierGainKnobLabel.Text = 'First Amplifier Gain';
            app.FirstAmplifierGainKnob = uiknob(app.Amplifier1Tab, 'continuous');
            app.FirstAmplifierGainKnob.Limits = [5 20];
            app.FirstAmplifierGainKnob.ValueChangingFcn = createCallbackFcn(app, @FirstAmplifierGainKnobValueChanging, true);
            app.FirstAmplifierGainKnob.Value = 5;
            app.DownConverterTab = uitab(app.TabGroup);
            app.DownConverterTab.Title = 'Down Converter';
            app.UIAxes3 = uiaxes(app.DownConverterTab);
            title(app.UIAxes3, 'Tx Mixer')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'Power [W]')
            zlabel(app.UIAxes3, 'Z')
            app.VCOTab = uitab(app.TabGroup);
            app.VCOTab.Title = 'VCO';
            app.TargetTab = uitab(app.TabGroup);
            app.TargetTab.Title = 'Target';
            app.VCOAmplifierTab = uitab(app.TabGroup);
            app.VCOAmplifierTab.Title = 'VCO Amplifier';
            app.UpConverterTab = uitab(app.TabGroup);
            app.UpConverterTab.Title = 'Up Converter';
            app.Amplifier2Tab = uitab(app.TabGroup);
            app.Amplifier2Tab.Title = 'Amplifier 2';
            app.ReceivedSignalInformationTab = uitab(app.TabGroup);
            app.ReceivedSignalInformationTab.Title = 'Received Signal Information';
            app.OriginalWaveFreq = uiaxes(app.ReceivedSignalInformationTab);
            title(app.OriginalWaveFreq, 'Frequency Spectrum')
            xlabel(app.OriginalWaveFreq, 'Frequency [Hz]')
            ylabel(app.OriginalWaveFreq, 'Amplitude')
            zlabel(app.OriginalWaveFreq, 'Z')
            app.OriginalSignalPanel = uipanel(app.UIFigure);
            app.OriginalSignalPanel.Title = 'Original Signal';
            app.ReceivedSignalPanel = uipanel(app.UIFigure);
            app.ReceivedSignalPanel.Title = 'Received Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UAVTrackingRadarSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end